classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        Label_2                    matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        Button_2                   matlab.ui.control.Button
        Reduce_Button              matlab.ui.control.Button
        Clear_Button               matlab.ui.control.Button
        Load_fileButton            matlab.ui.control.Button
        Change_tonebutton          matlab.ui.control.Button
        ChangeTonenEditField       matlab.ui.control.NumericEditField
        ChangeTonenEditFieldLabel  matlab.ui.control.Label
        Change_speedbutton         matlab.ui.control.Button
        ChangeSpeednEditField      matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        RecordingTimesSlider       matlab.ui.control.Slider
        RecordingTimesSliderLabel  matlab.ui.control.Label
        LoadingpleasewaitLabel     matlab.ui.control.Label
        record_voice               matlab.ui.control.Button
        Button                     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Property % Description
        xx; %记录录音数据
        yy;%记录文件数据
        recObj;
        fs;
        recordingSec;
        miniL;                                 
        mnlong;
        time;
        v;%音调改变倍数
        s ;%速度改变倍数
        path;
        signal;
    end
    
    methods (Access = private)
        
        %
        function [f,t]=enframe(app,x,win,inc)
            nx=length(x(:));
            nwin=length(win);
            if (nwin == 1)
                len = win;
            else
                len = nwin;
            end
            if (nargin < 3)
               inc = len;
            end
            nf = fix((nx-len+inc)/inc);
            f=zeros(nf,len);
            indf= inc*(0:(nf-1)).';
            inds = (1:len);
            f(:) = x(indf(:,ones(1,len))+inds(ones(nf,1),:));
            if (nwin > 1)
                w = win(:)';
                f = f .* w(ones(nf,1),:);
            end
            if nargout>1
                t=(1+len)/2+indf;
            end
            
            end

        %
        function soundSegment=findSegment(app,express)
            if express(1)==0
                voicedIndex=find(express);   % 寻找express中为1的位置
            else
                voicedIndex=express;
            end

            soundSegment = [];
            k = 1;
            soundSegment(k).begin = voicedIndex(1);            % 设置第一组有话段的起始位置
            for i=1:length(voicedIndex)-1
            	if voicedIndex(i+1)-voicedIndex(i)>1          % 本组有话段结束
            		soundSegment(k).end = voicedIndex(i);      % 设置本组有话段的结束位置
            		soundSegment(k+1).begin = voicedIndex(i+1);% 设置下一组有话段的起始位置
            		k = k+1;
            	end
            end
            soundSegment(k).end = voicedIndex(end);            % 最后一组有话段的结束位置
            % 计算每组有话段的长度
            for i=1 :k
                soundSegment(i).duration=soundSegment(i).end-soundSegment(i).begin+1;
            end

        end

        %
        function [y] = linsmoothm(app,x,n)
            if nargin< 2
                n=3;
            end
            win=hanning(n);                        % 用hanning窗
            win=win/sum(win);                      % 归一化
            x=x(:)';                               % 把x转换为行序列

            len=length(x);
            y= zeros(len,1);                       % 初始化y
            % 对x序列前后补n个数,以保证输出序列与x相同长
            if mod(n, 2) ==0
                l=n/2;
                x = [ones(1,1)* x(1) x ones(1,l)* x(len)]';
            else
                l=(n-1)/2;
                x = [ones(1,1)* x(1) x ones(1,l+1)* x(len)]';
            end
            % 线性平滑处理
            for k=1:len
                y(k) = win'* x(k:k+ n- 1);
            end

        end

        %
        function ff=lpcff(app,ar,np)
            [nf,p1]=size(ar);
            if nargin<2 ,np=p1-1; end
            ff=(fft(ar.',2*np+2).').^(-1);
            ff=ff(1:length(ff)/2);
        end

        %
        function lsf=lpctolsf(app,a)
            a=a(:);                       % 将a转换为列向量
            % 如果a不是实数，输出错误信息：LSF不适用于复多项式的求解
            if ~isreal(a)
                error('Line spectral frequencies are not defined for complex polynomials.');
            end
            % 如果a(1)不为1,将矩阵a的每个元素除以a(1)再赋给矩阵
            if a(1) ~= 1.0
                a=a./a(1);
            end
            % 如果a的根不在单位圆内,显示错误信息并返回
            if (max(abs(roots(a))) >= 1.0)
                error ('The polynomial must have all roots inside of the unit circle. ');
                return;
            end
            %求对称和反对称多项式的系数
            p=length(a) - 1;              % 求对称和反对称多项式的阶次
            a1=[a;0];                     % 给行矩阵a再增加一个元素0的行
            a2=a1(end:-1:1);              % a2的第一行为a1的最后一行，最后一行为al的第一行
            P1=a1+a2;                     % 按式(3-82)求对称多项式的系数
            Q1=a1-a2;                     % 按式(3-83)求反对称多项式的系数
            %如果阶次p为偶数次，按式(3-89)和式(3-90)从P1去掉实数根z=-1，从Q1去掉实数根z=1
            %如果阶次为奇数次，从Q1去掉实数根z=1及z=-1
            if rem(p,2)                  % 求解P除以2的余数，如果P为奇数次。余数为1，否则为0
                Q=deconv(Q1,[1 0 -1]);     % 奇数阶次，从Q1去掉实数根z=1及z=-1
                P=P1;
            else                          % P为偶数阶次执行下面的操作
                Q=deconv(Q1,[1 -1]);       % 从Q1去掉实数根z=1
                P=deconv(P1, [1 1] );      % 从P1去掉实数根z=-1
            end
            rP=roots(P);                  % 求去掉实根后的多项式P的根
            rQ=roots(Q) ;                 % 求去掉实根后的多项式Q的根
            aP=angle(rP(1: 2:end) ) ;     % 将多项式P的根转换为角度(为归一化角频率)赋给ap
            aQ=angle(rQ(1: 2:end));       % 将多项式Q的根转换为角度(为归一化角频率)赋给aQ
            lsf= sort([aP; aQ]);          % 将P、Q的根(归一化角频率)按从小到大的顺序排序后即为lsf

            
        end

        %
        function a=lsftolpc(app,lsf)
            % 如果线谱频率lsf是复数，则返回错误信息
            if (~isreal(lsf))
                error ('Line spectral frequencies must be real. ' ) ;
            end
            % 如果线谱频率lsf不在O～pi范围，则返回错误信息
            if (max(lsf) > pi || min(lsf)< 0)
                error ( 'Line spectral frequencies must be between 0 and pi. ' ) ;
            end
            lsf=lsf(:);                   % 将lsf转换为列向量
            p=length(lsf);                % lsf阶次为p
            % 用lsf形成零点
            z= exp(j * lsf);
            rP=z(1:2:end);                % 把奇次z(1)、z(3)到z(p-1)赋给rP
            rQ=z(2:2:end);                % 把偶次z(2)、z(4)到z(p)赋给rQ
            % 考虑共轭复根
            rQ=[rQ;conj(rQ)];             % 把rQ的共轭复根赋上
            rP=[rP;conj(rP)];             % 把rP的共轭复根赋上
            % 构成多项式P和Q，注意必须是实系数
            Q =poly(rQ);
            P =poly(rP);
            % 考虑z=1和z=-1以形成对称和反对称多项式
            if rem(p,2)
                % 如果是奇数阶次，则z=+l和z=-1都是Q1(z)的根
                Q1=conv(Q,[1 0 -1]);
                P1=P;
            else
                % 如果是偶数阶次，z=-1是对称多项式P1(z)的根，z=1是反对称多项式Ql(z)的根
                Q1=conv(Q,[1 -1]);
                P1=conv(P,[1 1]);
            end
            % 按式(3-87)由P1和Q1求解LPC系数
            a=.5 * (P1+Q1);
            a(end)=[];                    %最后一个系数是O，不返回

        end

        %
        function [voiceseg,vsl,SF,Ef,period]=pitch_Ceps(app,x,wnd,inc,T1,fs)
            if nargin<6, app.miniL=10; end
            if length(wnd)==1
                wlen=wnd;               % 求出帧长
            else
                wlen=length(wnd);
            end
            y  = app.enframe(x,wnd,inc)';                  % 分帧
            [voiceseg,vsl,SF,Ef]=app.pitch_vad(x,wnd,inc,T1,app.miniL);   % 基音的端点检测
            fn=length(SF);
            lmin=fix(fs/500);                           % 基音周期的最小值
            lmax=fix(fs/60);                            % 基音周期的最大值
            period=zeros(1,fn);                         % 基音周期初始化
            for k=1:fn
                if SF(k)==1                             % 是否在有话帧中
                    y1=y(:,k).*hamming(wlen);           % 取来一帧数据加窗函数
                    Xx=fft(y1);                         % FFT  %change
                    a=2*log(abs(Xx)+eps);               % 取模值和对数
                    b=ifft(a);                          % 求取倒谱
                    [R(k),Lc(k)]=max(b(lmin:lmax));     % 在lmin和lmax区间中寻找最大值
                    period(k)=Lc(k)+lmin-1;             % 给出基音周期
                end
            end

        end

        %
        function [voiceseg,vosl,SF,Ef]=pitch_vad(app,x,wnd,inc,T1,miniL)
            if nargin<5, miniL=10; end
            y=app.enframe(x,wnd,inc)';             % 分帧
            fn=size(y,2);                      % 求帧数
            if length(wnd)==1
                wlen=wnd;               % 求出帧长
            else
                wlen=length(wnd);
            end

            for i=1:fn
                Sp = abs(fft(y(:,i)));                    % FFT取幅值
                Sp = Sp(1:wlen/2+1);	                  % 只取正频率部分
                Esum(i) = sum(Sp.*Sp);                    % 计算能量值
                prob = Sp/(sum(Sp));	                  % 计算概率
                H(i) = -sum(prob.*log(prob+eps));         % 求谱熵值
            end
            hindex=find(H<0.1);
            H(hindex)=max(H);
            Ef=sqrt(1 + abs(Esum./H));                    % 计算能熵比
            Ef=Ef/max(Ef);                                % 归一化

            zindex=find(Ef>=T1);                          % 寻找Ef中大于T1的部分
            zseg=findSegment(app,zindex);                     % 给出端点检测各段的信息
            zsl=length(zseg);                             % 给出段数
            j=0;
            SF=zeros(1,fn);
            for k=1 : zsl                                 % 在大于T1中剔除小于miniL的部分
                if zseg(k).duration>=miniL
                    j=j+1;
                    in1=zseg(k).begin;
                    in2=zseg(k).end;
                    voiceseg(j).begin=in1;
                    voiceseg(j).end=in2;
                    voiceseg(j).duration=zseg(k).duration;
                    SF(in1:in2)=1;                        % 设置SF
                end
            end
            vosl=length(voiceseg);                        % 有话段的段数
        end

        %
        function ReconstructedSignal=OverlapAdd2(app,XNEW,yphase,windowLen,ShiftLen)
            if nargin<2
                yphase=angle(XNEW);
            end
            if nargin<3
                windowLen=size(XNEW,1)*2;
            end
            if nargin<4
                ShiftLen=windowLen/2;
            end
            if fix(ShiftLen)~=ShiftLen
                ShiftLen=fix(ShiftLen);
                disp('The shift length have to be an integer as it is the number of samples.')
                disp(['shift length is fixed to ' num2str(ShiftLen)])
            end

            [FreqRes ,FrameNum]=size(XNEW);

            Spec=XNEW.*exp(j*yphase);

            if mod(windowLen,2) %if FreqResol is odd
                Spec=[Spec;flipud(conj(Spec(2:end,:)))];
            else
                Spec=[Spec;flipud(conj(Spec(2:end-1,:)))];
            end
            sig=zeros((FrameNum-1)*ShiftLen+windowLen,1);
            weight=sig;
            for i=1:FrameNum
                start=(i-1)*ShiftLen+1;
                spec=Spec(:,i);
                sig(start:start+windowLen-1)=sig(start:start+windowLen-1)+real(ifft(spec,windowLen));
            end
            ReconstructedSignal=sig;
            
        end
        

        %
        function output=SpectralSub(app,signal,wlen,inc,NIS,a,b)
            wnd=hamming(wlen);                      % 设置窗函数
            N=length(signal);                       % 计算信号长度

            y=app.enframe(signal,wnd,inc)';             % 分帧
            fn=size(y,2);                           % 求帧数

            y_fft = fft(y);                         % FFT
            y_a = abs(y_fft);                       % 求取幅值
            y_phase=angle(y_fft);                   % 求取相位角
            y_a2=y_a.^2;                            % 求能量
            Nt=mean(y_a2(:,1:NIS),2);               % 计算噪声段平均能量
            nl2=wlen/2+1;                           % 求出正频率的区间

            for i = 1:fn                           % 进行谱减
                for k= 1:nl2
                    if y_a2(k,i)>a*Nt(k)
                        temp(k) = y_a2(k,i) - a*Nt(k);
                    else
                        temp(k)=b*y_a2(k,i);
                    end
                    U(k)=sqrt(temp(k));             % 把能量开方得幅值
                end
                X(:,i)=U;
            end
            output=app.OverlapAdd2(X,y_phase(1:nl2,:),wlen,inc);   % 合成谱减后的语音
            Nout=length(output);                    % 把谱减后的数据长度补足与输入等长
            if Nout>N
                output=output(1:N);
            elseif Nout<N
                output=[output; zeros(N-Nout,1)];
            end
            output=output/max(abs(output));         % 幅值归一

        end
        
        
        %
        function  snr=SNR_Calc(app,I,In)
                % 计算带噪语音信号的信噪比
                % I 是纯语音信号
                % In 是带噪的语音信号
                % 信噪比计算公式是
                % snr=10*log10(Esignal/Enoise)
                I=I(:)';                             % 把数据转为一列
                In=In(:)';
                Ps=sum((I-mean(I)).^2);              % 信号的能量
                Pn=sum((I-In).^2);                      % 噪声的能量
                snr=10*log10(Ps/Pn);                 % 信号的能量与噪声的能量之比，再求分贝值
            
        end


            function y=pitfilterm1(app,x,vseg,vsl)
                y=zeros(size(x));             % 初始化
                for i=1 : vsl                 % 有段数据
                    ixb=vseg(i).begin;        % 该段的开始位置
                    ixe=vseg(i).end;          % 该段的结束位置
                    u0=x(ixb:ixe);            % 取来一段数据
                    y0=medfilt1(u0,5);        % 5点的中值滤波
                    v0=app.linsmoothm(y0,5);      % 线性平滑
                    y(ixb:ixe)=v0;            % 赋值给y
                end
            end

        %
        function frameTime=FrameTimeC(app,frameNum,framelen,inc,fs)
            % 分帧后计算每帧对应的时间
            frameTime=(((1:frameNum)-1)*inc+framelen/2)/fs;
        end
            
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fs = 8000;
            app.recordingSec = 5;
            app.s = 1;
            app.v = 1;
            app.miniL=10;                                 
            app.mnlong=5; 
            app.ChangeSpeednEditField.Value = 0;
            app.xx = 0;
            app.yy = 0;
            app.recObj = 0;
            app.signal = 0;
            set(app.LoadingpleasewaitLabel,'Text','Welcome to use!');
        end

        % Button pushed function: record_voice
        function record_voicePushed(app, event)

            app.recObj = audiorecorder(8000,16,1);
            set(app.LoadingpleasewaitLabel,'Text','Start speaking')
            recordblocking(app.recObj, app.recordingSec);
            set(app.LoadingpleasewaitLabel,'Text','End speaking')
            % 获取录音数据
            app.xx = getaudiodata(app.recObj);
            plot(app.UIAxes, app.xx)
            app.yy = 0;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 回放录音数据

            if app.yy == 0
                plot(app.UIAxes, app.xx)
                set(app.LoadingpleasewaitLabel, 'Text', 'PlayingA...');
                sound(app.xx, 8000, 16)
                pause(app.recordingSec)
                set(app.LoadingpleasewaitLabel, 'Text', 'End of Playing');
            else

                plot(app.UIAxes, app.yy)
                set(app.LoadingpleasewaitLabel, 'Text', 'Playing...');
                sound(app.yy, 8000, 16)
                pause(app.recordingSec)
                set(app.LoadingpleasewaitLabel, 'Text', 'End of Playing');
            end
           
        end

        % Value changed function: RecordingTimesSlider
        function RecordingTimesSliderValueChanged(app, event)

            app.recordingSec = ceil(app.RecordingTimesSlider.Value);
            if  app.recordingSec == 0
                app.recordingSec = 1;
                set(app.LoadingpleasewaitLabel, 'Text', 'Min recording time is 1 sec');
                
            else
                printtext = strcat('Recording time is now: ', string(app.recordingSec), ' sec.');
                set(app.LoadingpleasewaitLabel, 'Text', printtext);
                
                % set(app.UIAxes, "XTick", 0:0.5:recordingSec)
            end
            
        end

        % Button pushed function: Change_speedbutton
        function Change_speedbuttonButtonPushed(app, event)
            if app.xx ==0
                errordlg("没有读入录音音频信号！","错误");
                return
            end
            app.xx=app.xx-mean(app.xx);                  % 去除直流分量
            x=app.xx/max(abs(app.xx));
            
            N=length(x);                                 % 数据长度
            app.time=(0:N-1)/app.fs;                          % 信号的时间刻度
            wlen=240;                                 % 帧长
            inc=80;                                   % 帧移
            overlap=wlen-inc;                         % 重叠长度
            tempr1=(0:overlap-1)'/overlap;            % 斜三角窗函数w1
            tempr2=(overlap-1:-1:0)'/overlap;         % 斜三角窗函数w2
            n2=1:wlen/2+1;                            % 正频率的下标值
            X=app.enframe(x,wlen,inc)';               % 分帧
            fn=size(X,2);                             % 帧数
            T1=0.1; r2=0.5;                           % 端点检测参数
            app.miniL=10;                             % 有话段最短帧数
            app.mnlong=5;                             % 元音主体最短帧数
            ThrC=[10 15];                             % 阈值
            p=12;                                     % LPC阶次
            frameTime=app.FrameTimeC(fn,wlen,inc,app.fs);     % 计算每帧的时间刻度
            % 语音变速实验
            set(app.LoadingpleasewaitLabel,'text','Raning...')
            rate=double(app.s);  %输入伸缩长度比例
            

            for i=1 : fn                              % 求取每帧的预测系数和增益
                u=X(:,i);
                [ar,g]=lpc(u,p);
                AR_coeff(:,i)=ar;
                Gain(i)=g;
            end
            
            % 基音检测
            [voiceseg,vosl,SF,Ef,period]=app.pitch_Ceps(x,wlen,inc,T1,app.fs);  %基于倒谱法的基音周期检测
            Dpitch=app.pitfilterm1(period,voiceseg,vosl);        % 对T0进行平滑处理求出基音周期T0

            tal=0;                                    % 初始化
            zint=zeros(p,1);
            
            % LSP参数的提取
            for i=1 : fn
                a2=AR_coeff(:,i);                     % 取来本帧的预测系数
                lsf=app.lpctolsf(a2);                       % 调用ar2lsf函数求出lsf
                Glsf(:,i)=lsf;                        % 把lsf存储在Glsf数组中
            end
            set(app.LoadingpleasewaitLabel,'text','...')
            % 通过内插把相应数组缩短或伸长
            fn1=floor(rate*fn);                        % 设置新的总帧数fn1
            
            Glsfm=interp1((1:fn),Glsf',linspace(1,fn,fn1))'; % 把LSF系数内插
            set(app.LoadingpleasewaitLabel,'text','...')
            Dpitchm=interp1(1:fn,Dpitch,linspace(1,fn,fn1)); % 把基音周期内插
            Gm=interp1((1:fn),Gain,linspace(1,fn,fn1)); %把增益系数内插
            SFm=interp1((1:fn),SF,linspace(1,fn,fn1));  %把SF系数内插

            %语音合成
            
            for i=1:fn1
                lsf=Glsfm(:,i);                       % 获取本帧的lsf参数
                ai=app.lsftolpc(lsf);                       % 调用lsf2ar函数把lsf转换成预测系数ar
                sigma=sqrt(Gm(i));

                if SFm(i)==0                          % 无话帧
                    excitation=randn(wlen,1);         % 产生白噪声
                    [synt_frame,zint]=filter(sigma,ai,excitation,zint);
                else                                  % 有话帧
                    PT=round(Dpitchm(i));             % 取周期值
                    exc_syn1 =zeros(wlen+tal,1);      % 初始化脉冲发生区
                    exc_syn1(mod(1:tal+wlen,PT)==0)=1;% 在基音周期的位置产生脉冲，幅值为1
                    exc_syn2=exc_syn1(tal+1:tal+inc); % 计算帧移inc区间内的脉冲个数
                    index=find(exc_syn2==1);
                    excitation=exc_syn1(tal+1:tal+wlen);% 这一帧的激励脉冲源

                    if isempty(index)                 % 帧移inc区间内没有脉冲
                        tal=tal+inc;                  % 计算下一帧的前导零点
                    else                              % 帧移inc区间内有脉冲
                        eal=length(index);            % 计算有几个脉冲
                        tal=inc-index(eal);           % 计算下一帧的前导零点
                    end
                    gain=sigma/sqrt(1/PT);            % 增益
                    [synt_frame,zint]=filter(gain,ai,excitation,zint);%用激励脉冲合成语音
                end

                if i==1                               % 若为第1帧
                    output=synt_frame;            % 不需要重叠相加,保留合成数据
                else
                    M=length(output);             % 重叠部分的处理
                    output=[output(1:M-overlap); output(M-overlap+1:M).*tempr1+...
                        synt_frame(1:overlap).*tempr2; synt_frame(overlap+1:wlen)];
                end
            end
            output(find(isnan(output)))=0;
            bn=[0.964775   -3.858862   5.788174   -3.858862   0.964775]; % 滤波器系数
            an=[1.000000   -3.928040   5.786934   -3.789685   0.930791];
            output=filter(bn,an,output);             % 高通滤波
            output=output/max(abs(output));          % 幅值归一化
             
            sound(output,app.fs);
            plot(app.UIAxes2, output)
            set(app.LoadingpleasewaitLabel,'text','End of Raning')


        end

        % Value changed function: ChangeSpeednEditField
        function ChangeSpeednEditFieldValueChanged(app, event)
            value = app.ChangeSpeednEditField.Value;
            app.s = value;
            if value == 0
                errordlg("变化倍数不能为0！","错误");
            else
                print_text = strcat('Speed Changing multiplier is', string(app.s), '.');
                set(app.LoadingpleasewaitLabel, 'Text', print_text);
            end
        

        end

        % Value changed function: ChangeTonenEditField
        function ChangeTonenEditFieldValueChanged(app, event)
            value = app.ChangeTonenEditField.Value;
            app.v = value;
            if value == 0
                errordlg("变化倍数不能为0！","错误");
            else
                print_text = strcat('Tone Changing multiplier is', string(app.v), '.');
                set(app.LoadingpleasewaitLabel, 'Text', print_text);
            end
        end

        % Button pushed function: Change_tonebutton
        function Change_tonebuttonButtonPushed(app, event)
            %app.xx = getaudiodata(app.recObj);
            if app.xx ==0
                errordlg("没有读入录音音频信号！","错误");
                return
            end
            app.xx=app.xx-mean(app.xx);                  % 去除直流分量
            x=app.xx/max(abs(app.xx));                   % 归一化
            
            lx=length(x);                             % 数据长度
            app.time=(0:lx-1)/app.fs;                         % 求出对应的时间序列
            wlen=240;                                 % 设定帧长
            inc=80;                                   % 设定帧移的长度
            overlap=wlen-inc;                         % 重叠长度
            tempr1=(0:overlap-1)'/overlap;            % 斜三角窗函数w1
            tempr2=(overlap-1:-1:0)'/overlap;         % 斜三角窗函数w2
            n2=1:wlen/2+1;                            % 正频率的下标值
            X=app.enframe(x,wlen,inc)';                   % 按照参数进行分帧
            fn=size(X,2);                             % 总帧数
            T1=0.1; r2=0.5;                           % 端点检测参数
            app.miniL=10;                                 % 有话段最短帧数
            app.mnlong=5;                                 % 元音主体最短帧数
            ThrC=[10 15];                             % 阈值
            p=12;                                     % LPC阶次
            frameTime=app.FrameTimeC(fn,wlen,inc,app.fs);     % 计算每帧的时间刻度
            set(app.LoadingpleasewaitLabel,'text','Raning...')
            rate=double(app.v); % 输入基音频率增降比例
            for i=1 : fn                              % 求取每帧的预测系数和增益
                u=X(:,i);
                [ar,g]=lpc(u,p);
                AR_coeff(:,i)=ar;
                Gain(i)=g;
            end

            % 基音检测
            [voiceseg,vosl,SF,Ef,period]=app.pitch_Ceps(x,wlen,inc,T1,app.fs); %基于倒谱法的基音周期检测
            Dpitch=app.pitfilterm1(period,voiceseg,vosl);       % 对T0进行平滑处理求出基音周期T0

            if rate>1, sign=-1; else sign=1; end
            lmin=floor(app.fs/450);                       % 基音周期的最小值
            lmax=floor(app.fs/60);                        % 基音周期的最大值
            deltaOMG = sign*100*2*pi/app.fs;              % 根值顺时针或逆时针旋转量dθ
            Dpitchm=Dpitch/rate;                      % 增减后的基音周期
            % Dfreqm=Dfreq*rate;                        % 增减后的基音频率

            tal=0;                                    % 初始化
            zint=zeros(p,1);

            for i=1 : fn
                a=AR_coeff(:,i);                      % 取得本帧的AR系数
                sigma=sqrt(Gain(i));                  % 取得本帧的增益系数

                if SF(i)==0                           % 无话帧
                    excitation=randn(wlen,1);         % 产生白噪声
                    [synt_frame,zint]=filter(sigma,a,excitation,zint);
                else                                  % 有话帧
                    PT=floor(Dpitchm(i));             % 把周期值变为整数
                    if PT<lmin, PT=lmin; end          % 判断修改后的周期值有否超限
                    if PT>lmax, PT=lmax; end
                    ft=roots(a);                      % 对预测系数求根
                    ft1=ft;
                    %增加共振峰频率，实轴上方的根顺时针转，下方的根逆时针转，求出新的根值
                    for k=1 : p
                        if imag(ft(k))>0
                            ft1(k) = ft(k)*exp(j*deltaOMG);
                        elseif imag(ft(k))<0
                            ft1(k) = ft(k)*exp(-j*deltaOMG);
                        end
                    end
                    ai=poly(ft1);                     % 由新的根值重新组成预测系数

                    exc_syn1 =zeros(wlen+tal,1);      % 初始化脉冲发生区
                    exc_syn1(mod(1:tal+wlen,PT)==0)=1;% 在基音周期的位置产生脉冲，幅值为1
                    exc_syn2=exc_syn1(tal+1:tal+inc); % 计算帧移inc区间内的脉冲个数
                    index=find(exc_syn2==1);
                    excitation=exc_syn1(tal+1:tal+wlen);% 这一帧的激励脉冲源

                    if isempty(index)                 % 帧移inc区间内没有脉冲
                        tal=tal+inc;                  % 计算下一帧的前导零点
                    else                              % 帧移inc区间内有脉冲
                        eal=length(index);            % 计算脉冲个数
                        tal=inc-index(eal);           % 计算下一帧的前导零点
                    end
                    gain=sigma/sqrt(1/PT);            % 增益
                    [synt_frame,zint]=filter(gain,ai,excitation,zint);%用激励脉冲合成语音
                end

                if i==1                               % 若为第1帧
                    output=synt_frame;            % 不需要重叠相加,保留合成数据
                else
                    M=length(output);             % 重叠部分的处理
                    output=[output(1:M-overlap); output(M-overlap+1:M).*tempr1+...
                        synt_frame(1:overlap).*tempr2; synt_frame(overlap+1:wlen)];
                end
            end

            output(find(isnan(output)))=0;
            ol=length(output);                        % 把输出output延长至与输入信号xx等长
            if ol<lx
                output1=[output; zeros(lx-ol,1)];
            else
                output1=output(1:lx);
            end
            bn=[0.964775   -3.858862   5.788174   -3.858862   0.964775]; % 滤波器系数
            an=[1.000000   -3.928040   5.786934   -3.789685   0.930791];
            output=filter(bn,an,output1);             % 高通滤波
            output=output/max(abs(output));           % 幅值归一化

            sound(output,app.fs);
            plot(app.UIAxes2, output)
            set(app.LoadingpleasewaitLabel,'text','End of Raning')
        end

        % Button pushed function: Load_fileButton
        function Load_fileButtonPushed(app, event)
            [filename,pathname]=uigetfile({'*.wav;*.mp3','*.wav;*.mp3'},'选择声音文件');
            if isequal([filename pathname],[0,0])
                errordlg("没有选中文件","错误")
            else
                app.path = strcat(pathname, filename);%选择的声音文件路径
                msgbox("读取成功","提示")
            end
            [y,app.fs] = audioread(app.path);
            app.yy = y;
            app.xx = 0;

        end

        % Button pushed function: Clear_Button
        function Clear_ButtonPushed(app, event)
            app.xx =0;
            app.yy =0;
            msgbox("清零成功","提示")
        end

        % Button pushed function: Reduce_Button
        function Reduce_ButtonPushed(app, event)
            app.yy=app.yy-mean(app.yy);                         % 消除直流分量
            x=app.yy/max(abs(app.yy));                      % 幅值归一化

            IS=0.25;                                % 设置前导无话段长度
            wlen=200;                               % 设置帧长为25ms
            inc=80;                                 % 设置帧移为10ms
            SNR=5;                                  % 设置信噪比SNR
            N=length(x);                            % 信号长度
            app.time=(0:N-1)/app.fs;                        % 设置时间

            %signal=awgn(x,SNR,'measured','db');              % 叠加噪声
            snr1=app.SNR_Calc(x,app.signal);            % 计算初始信噪比
            NIS=fix((IS*app.fs-wlen)/inc +1);           % 求前导无话段帧数

            a=4; b=0.001;                           % 设置参数a和b
            output=app.SpectralSub(app.signal,wlen,inc,NIS,a,b); % 谱减
            snr2=app.SNR_Calc(x,output);            % 计算谱减后的信噪比
            snr=snr2-snr1;
            sound(output,app.fs);
            plot(app.UIAxes2, output)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.yy=app.yy-mean(app.yy);                         % 消除直流分量
            x=app.yy/max(abs(app.yy));
            SNR=5;
            app.signal=awgn(x,SNR,'measured','db');
            plot(app.UIAxes2, app.signal)
            sound(app.signal,app.fs);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {497, 497};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {443, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.LeftPanel);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '左子瑞 520021910495';
            app.RightPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '播放';
            app.record_voice = uibutton(app.RightPanel, 'push');
            app.record_voice.ButtonPushedFcn = createCallbackFcn(app, @record_voicePushed, true);
            app.record_voice.Text = '录音';
            app.LoadingpleasewaitLabel = uilabel(app.RightPanel);
            app.RecordingTimesSliderLabel = uilabel(app.RightPanel);
            app.RecordingTimesSliderLabel.Text = {'Recording'; '         Time'; '              (s)'};
            app.RecordingTimesSlider = uislider(app.RightPanel);
            app.RecordingTimesSlider.Limits = [1 10];
            app.RecordingTimesSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.RecordingTimesSlider.ValueChangedFcn = createCallbackFcn(app, @RecordingTimesSliderValueChanged, true);
            app.RecordingTimesSlider.Value = 1;
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = {'Change'; 'Speed'; '(×n)'};
            app.ChangeSpeednEditField = uieditfield(app.RightPanel, 'numeric');
            app.ChangeSpeednEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeSpeednEditFieldValueChanged, true);
            app.Change_speedbutton = uibutton(app.RightPanel, 'push');
            app.Change_speedbutton.ButtonPushedFcn = createCallbackFcn(app, @Change_speedbuttonButtonPushed, true);
            app.Change_speedbutton.Text = '变速不变调';
            app.ChangeTonenEditFieldLabel = uilabel(app.RightPanel);
            app.ChangeTonenEditFieldLabel.Text = {'Change'; 'Tone'; '(×n)'; ''};
            app.ChangeTonenEditField = uieditfield(app.RightPanel, 'numeric');
            app.ChangeTonenEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeTonenEditFieldValueChanged, true);
            app.Change_tonebutton = uibutton(app.RightPanel, 'push');
            app.Change_tonebutton.ButtonPushedFcn = createCallbackFcn(app, @Change_tonebuttonButtonPushed, true);
            app.Change_tonebutton.Text = '变调不变速';
            app.Load_fileButton = uibutton(app.RightPanel, 'push');
            app.Load_fileButton.ButtonPushedFcn = createCallbackFcn(app, @Load_fileButtonPushed, true);
            app.Load_fileButton.Text = '导入音频';
            app.Clear_Button = uibutton(app.RightPanel, 'push');
            app.Clear_Button.ButtonPushedFcn = createCallbackFcn(app, @Clear_ButtonPushed, true);
            app.Clear_Button.Text = '清零';
            app.Reduce_Button = uibutton(app.RightPanel, 'push');
            app.Reduce_Button.ButtonPushedFcn = createCallbackFcn(app, @Reduce_ButtonPushed, true);
            app.Reduce_Button.Text = '降噪';
            app.Button_2 = uibutton(app.RightPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '施加白噪声';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end